classdef tercerProyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RecordButton              matlab.ui.control.Button
        DisplayButton             matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        TransformButton           matlab.ui.control.Button
        DryingMachineLampLabel    matlab.ui.control.Label
        DryingMachineLamp         matlab.ui.control.Lamp
        WaterFlowingLampLabel     matlab.ui.control.Label
        WaterFlowingLamp          matlab.ui.control.Lamp
        PhoneRingtoneLampLabel    matlab.ui.control.Label
        PhoneRingtoneLamp         matlab.ui.control.Lamp
        CarClaxonLampLabel        matlab.ui.control.Label
        CarClaxonLamp             matlab.ui.control.Lamp
        ComputerBootingLampLabel  matlab.ui.control.Label
        ComputerBootingLamp       matlab.ui.control.Lamp
        CompareButton             matlab.ui.control.Button
        BrowseButton              matlab.ui.control.Button
        MagnitudeEditFieldLabel   matlab.ui.control.Label
        MagnitudeEditField        matlab.ui.control.EditField
    end

    
    properties (Access = public)
        magnitude;
    end
    

    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.DryingMachineLamp.Color = 'white';
            app.WaterFlowingLamp.Color = 'white';
            app.PhoneRingtoneLamp.Color = 'white';
            app.CarClaxonLamp.Color = 'white';
            app.ComputerBootingLamp.Color = 'white';
            
            
            audioObject = audiorecorder(8000,8,1);    
            %msgbox('inicia grabacion');
            recordblocking(audioObject,1);
            msgbox('ya se grabo');
            assignin('base','audioObject',audioObject);
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            audioObject = evalin('base', 'audioObject');
            soundFunction = getaudiodata(audioObject);
            Fs = audioObject.SampleRate;
            
            n=1;
 
            t=0:Fs*n-1;
            t=t/Fs;
            plot(app.UIAxes,t,soundFunction);
           
  
            sound(soundFunction,Fs);
        end

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
            audioObject = evalin('base', 'audioObject');
            soundFunction = getaudiodata(audioObject);
            fm = audioObject.SampleRate;
            
            Np=8192;                             % Número de puntos de la DFT
            L=length(soundFunction);             % Longitud de la señal
            Tm=(1/fm);                           % Tiempo de muestreo
            Mp=ceil(Np/2);                       % Mitad de puntos de la FFT
            wd=0:2*pi/Np:2*pi*(Np-1)/Np;         % Vector de Frec. discreta
            wdo=zeros(1,Np);                     % Vector de Frec. disc. organizadas
            wc=zeros(1,Np);                      % Vector de Frec. cont. en rad/seg
            fc=zeros(1,Np);                      % Vector de Frec. cont. en Hz
            z=abs(fft(soundFunction,Np));        % Magnitud de FFT de la señal
            zo=zeros(1,Np);                      % Vector para reorganizar la FFT
            t=0:Tm:Tm*(L-1);                     % Vector de tiempo
            
            % Reorganización de frecuencias
            wdo(Np-Mp+1:end)=wd(1:Mp);
            wdo(1:Np-Mp)=wd(Mp+1:end)-2*pi;
            
            % Frecuencia continua en Hz
            wc=wdo/Tm;
            fc=wc/(2*pi);
            
            % Reorganización de la FFT
            zo(Np-Mp+1:end)=z(1:Mp);
            zo(1:Np-Mp)=z(Mp+1:end);
            
            M=zo;
            freq=fc;
            
            %figure;
            plot(app.UIAxes2,freq,mag2db(M));
            %plot(freq,mag2db(M));
            %title('Magnitud de la transformada de Fourier');   
            
            %xlabel('Frecuencia (Hz)');
            %ylabel('Amplitude (dB)')
            
            %disp("maxima frecuencia: " + max(M));
            %disp("maxima magnitud en dB" + max(mag2db(M)))
            app.magnitude = M;
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)

            magnitudeInDb = int16(mag2db(max(app.magnitude)));
            %magnitudeInDb = 27;
           
            maxMagnitudeWashingMachine = 18;
            maxMagnitudeFlowingWater = 27;
            maxMagnitudeComputerStarting = 48;
            maxMagnitudeCarClaxon = 20;
            maxMagnitudeTelephoneRingtone = 50;
            app.MagnitudeEditField.Value = int2str(magnitudeInDb);
            if (magnitudeInDb <= maxMagnitudeWashingMachine*1.03 && magnitudeInDb >= maxMagnitudeWashingMachine * 0.97)
                app.DryingMachineLamp.Color = 'red';
            elseif (magnitudeInDb <= maxMagnitudeFlowingWater*1.03 && magnitudeInDb >= maxMagnitudeFlowingWater * 0.97)
                app.WaterFlowingLamp.Color = 'red';
            elseif (magnitudeInDb <= maxMagnitudeComputerStarting*1.03 && magnitudeInDb >= maxMagnitudeComputerStarting * 0.97)
                app.ComputerBootingLamp.Color = 'red';
            elseif (magnitudeInDb <= maxMagnitudeCarClaxon*1.03 && magnitudeInDb >= maxMagnitudeCarClaxon * 0.97)
                app.CarClaxonLamp.Color = 'red';
            elseif (magnitudeInDb <= maxMagnitudeTelephoneRingtone*1.03 && magnitudeInDb >= maxMagnitudeTelephoneRingtone * 0.97)
                app.PhoneRingtoneLamp.Color = 'red';
            end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.DisplayButton = uibutton(app.UIFigure, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sound Spectrum')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Sound Spectrum in the Frequency Domain')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'Amplitude (Db)')
            app.TransformButton = uibutton(app.UIFigure, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Text = 'Transform';
            app.DryingMachineLampLabel = uilabel(app.UIFigure);
            app.DryingMachineLampLabel.Text = 'Drying Machine';
            app.DryingMachineLamp = uilamp(app.UIFigure);
            app.WaterFlowingLampLabel = uilabel(app.UIFigure);
            app.WaterFlowingLampLabel.Text = 'Water Flowing';
            app.WaterFlowingLamp = uilamp(app.UIFigure);
            app.PhoneRingtoneLampLabel = uilabel(app.UIFigure);
            app.PhoneRingtoneLampLabel.Text = 'Phone Ringtone';
            app.PhoneRingtoneLamp = uilamp(app.UIFigure);
            app.CarClaxonLampLabel = uilabel(app.UIFigure);
            app.CarClaxonLampLabel.Text = 'Car Claxon';
            app.CarClaxonLamp = uilamp(app.UIFigure);
            app.ComputerBootingLampLabel = uilabel(app.UIFigure);
            app.ComputerBootingLampLabel.Text = 'Computer Booting';
            app.ComputerBootingLamp = uilamp(app.UIFigure);
            app.CompareButton = uibutton(app.UIFigure, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.Text = 'Browse';
            app.MagnitudeEditFieldLabel = uilabel(app.UIFigure);
            app.MagnitudeEditFieldLabel.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = tercerProyecto

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end